classdef EEG_Classification_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ClassifyButton             matlab.ui.control.Button
        EEGMotorImageryclassificationusingCNNLabel  matlab.ui.control.Label
        OutputEditFieldLabel       matlab.ui.control.Label
        OutputEditField            matlab.ui.control.EditField
        AccuracyEditField          matlab.ui.control.NumericEditField
        PredictionconfidenceLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        ScalogramLabel             matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            net = load("net.mat").net;
            xTest = load("xTest.mat").xTest;
            %yTest = load("yTest.mat").yTest;
            [preds, scrs] = classify(net, xTest(:, :, :, 1));
            app.AccuracyEditField.Value = double(max(scrs(1, :)));
            bar(app.UIAxes, categorical({'T0', 'T1'}), scrs(1, :));
            app.Image.ImageSource = xTest(:, :, :, 1);
            app.OutputEditField.Value = char(preds);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.EEGMotorImageryclassificationusingCNNLabel = uilabel(app.UIFigure);
            app.EEGMotorImageryclassificationusingCNNLabel.Text = 'EEG Motor Imagery classification using CNN';
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output:';
            app.OutputEditField = uieditfield(app.UIFigure, 'text');
            app.OutputEditField.Editable = 'off';
            app.AccuracyEditField = uieditfield(app.UIFigure, 'numeric');
            app.AccuracyEditField.Editable = 'off';
            app.PredictionconfidenceLabel = uilabel(app.UIFigure);
            app.PredictionconfidenceLabel.Text = 'Prediction confidence:';
            app.Image = uiimage(app.UIFigure);
            app.ScalogramLabel = uilabel(app.UIFigure);
            app.ScalogramLabel.Text = 'Scalogram';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Bar chart')
            ylabel(app.UIAxes, 'Accuracy')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_Classification_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end